classdef initView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MVPAlabtoolboxUIFigure       matlab.ui.Figure
        NewStudyTabGroup             matlab.ui.container.TabGroup
        InitialconfigurationTab      matlab.ui.container.Tab
        ConfigureMVPAButton          matlab.ui.control.Button
        StudylocationEditFieldLabel  matlab.ui.control.Label
        StudyLocationMVPAField       matlab.ui.control.EditField
        StudyLocationMVPAButton      matlab.ui.control.Button
        AnalysistypeLabel            matlab.ui.control.Label
        AnalysistypeDropDown         matlab.ui.control.DropDown
        LoadPreviousStudyPanel       matlab.ui.container.Panel
        SelectStudyButton            matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        OpenstudyfileLabel           matlab.ui.control.Label
        OpenStudyFileEditField       matlab.ui.control.EditField
        ExitButton                   matlab.ui.control.Button
        CreatenewstudyLabel          matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        MVPALogo                     matlab.ui.control.Image
        versionLabel                 matlab.ui.control.Label
        MVPAlabToolboxLabel          matlab.ui.control.Label
        plotUtilityButton            matlab.ui.control.Button
    end

    properties (Access = public)
        cfg struct; % Study configuration file
        studyView   % Study configuration app
        resultView
        isNew;
    end
    methods (Access = private)
        function getFocus(app)
            app.MVPAlabtoolboxUIFigure.Visible = 'off';
            app.MVPAlabtoolboxUIFigure.Visible = 'on';
        end
        
        function savecfgFile(app)
            cfg = app.cfg;
            save([app.cfg.study.studyLocation filesep 'cfg.mat'],'cfg');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cfg = mvpalab_init();
            app.versionLabel.Text = app.cfg.version;
        end

        % Callback function
        function exitButtonPushed(app, event)
            closereq
        end

        % Button pushed function: StudyLocationMVPAButton
        function studyLocationButtonPushed(app, event)
            % Folder selector
            app.cfg = mvpalab_init();
            openDir = fullfile(getenv('HOME'),'Desktop');
            app.cfg.study.studyLocation = uigetdir(openDir,'Select folder');
            app.getFocus();
            
            if app.cfg.study.studyLocation
                % If a previous study exists, select another location:
                if isfile([app.cfg.study.studyLocation '/cfg.mat'])
                    % Show warning and disable conf button:
                    m = ['This folder already contains a study ' ...
                        'file. Please select an empty folder.'];
                    title = 'Warning';
                    uialert(app.MVPAlabtoolboxUIFigure,m,title,'Icon',...
                        'warning');
                
                    app.ConfigureMVPAButton.Enable = 'off';
                    app.cfg.study.studyLocation = 0;
                else
                    % Update text field:
                    app.StudyLocationMVPAField.Value = ...
                        app.cfg.study.studyLocation;
                    
                    app.cfg.sf.filesLocation = ...
                        [app.cfg.study.studyLocation filesep 'sf'];
                    
                    % Enable conf button:
                    app.ConfigureMVPAButton.Enable = 'on';
                    
                    % Update analysis type:
                    app.cfg.study.analysis = app.AnalysistypeDropDown.Value;
                    
                    % Save the cfg file:
                    app.savecfgFile();
                end
            else
                app.StudyLocationMVPAField.Value = '';
                app.ConfigureMVPAButton.Enable = 'off';
            end
        end

        % Button pushed function: ConfigureMVPAButton
        function configureButtonPushed(app, event)
            % Check if exist:
            if ~isdir(app.cfg.study.studyLocation)
                msg = 'This folder does not exist';
                title = 'Warning';
                selection = uiconfirm(app.MVPAlabtoolboxUIFigure, ...
                    msg, title,...
                    'Options',{'Create folder','Cancel'},...
                    'DefaultOption',1,'CancelOption',2);
                
                if strcmp(selection,'Create folder')
                    mvpalab_mkdir(app.cfg.study.studyLocation)
                else
                    return
                end
            end
            
            % Update gui
            app.LoadButton.Enable = 'off';
            app.ConfigureMVPAButton.Enable = 'off';
            
            % Load study:
            app.isNew = 1;
            app.studyView = studyView(app);
        end

        % Button pushed function: SelectStudyButton
        function selectStudyButtonPushed(app, event)
            % File selector:
            openDir = fullfile(getenv('HOME'),'Desktop');
            [file,path] = uigetfile([openDir '/cfg.mat'], ...
                'Select a cfg.mat file');
            app.getFocus();
            
            if file
                if strcmp(file,'cfg.mat')
                    % Load study file:
                    temp = load([path file]);
                    
                    % Update cfg file:
                    app.cfg = mvpalab_backCompatibility(temp.cfg);
                    
                    % Update text field,file location and enable load button:
                    app.cfg.study.studyLocation = path;
                    app.OpenStudyFileEditField.Value = [path file];
                    app.LoadButton.Enable = 'on';
                    
                    % Save the cfg file:
                    app.savecfgFile();
                    
                else
                    % Show warning and disable load button
                    m = 'Please select a valid cfg.mat file.';
                    title = 'Warning';
                    uialert(app.MVPAlabtoolboxUIFigure, m, title, ...
                        'Icon','warning');
                    app.LoadButton.Enable = 'off';
                end
            else
                app.LoadButton.Enable = 'off';
                app.OpenStudyFileEditField.Value = '';
            end
        end

        % Button pushed function: LoadButton
        function loadButtonPushed(app, event)
            % Update gui buttons:
            app.LoadButton.Enable = 'off';
            app.ConfigureMVPAButton.Enable = 'off';
            
            % Reload study:
            temp = load([app.cfg.study.studyLocation filesep 'cfg.mat']);
            app.cfg = mvpalab_backCompatibility(temp.cfg);
            app.savecfgFile();
            
            % Start studyView:
            app.isNew = 0;
            app.studyView = studyView(app);
        end

        % Callback function: ExitButton, MVPAlabtoolboxUIFigure
        function MVPAlabtoolboxUIFigureCloseRequest(app, event)
            delete(app.studyView);
            delete(app.resultView);
            delete(app);
        end

        % Value changed function: AnalysistypeDropDown
        function AnalysistypeDropDownValueChanged(app, event)
            app.cfg.study.analysis = app.AnalysistypeDropDown.Value;
        end

        % Button pushed function: plotUtilityButton
        function plotUtilityButtonPushed(app, event)
            app.resultView = resultView();
        end

        % Value changed function: StudyLocationMVPAField
        function StudyLocationMVPAFieldValueChanged(app, event)
            app.cfg.study.studyLocation = app.StudyLocationMVPAField.Value;
            if ~isempty(app.cfg.study.studyLocation)
                app.ConfigureMVPAButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MVPAlabtoolboxUIFigure = uifigure('Visible', 'off');
            app.MVPAlabtoolboxUIFigure.Name = 'MVPAlab toolbox';
            app.MVPAlabtoolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @MVPAlabtoolboxUIFigureCloseRequest, true);
            app.NewStudyTabGroup = uitabgroup(app.MVPAlabtoolboxUIFigure);
            app.InitialconfigurationTab = uitab(app.NewStudyTabGroup);
            app.InitialconfigurationTab.Title = 'Initial configuration';
            app.ConfigureMVPAButton = uibutton(app.InitialconfigurationTab, 'push');
            app.ConfigureMVPAButton.ButtonPushedFcn = createCallbackFcn(app, @configureButtonPushed, true);
            app.ConfigureMVPAButton.Enable = 'off';
            app.ConfigureMVPAButton.Text = 'Configure...';
            app.StudylocationEditFieldLabel = uilabel(app.InitialconfigurationTab);
            app.StudylocationEditFieldLabel.Text = 'Study location:';
            app.StudyLocationMVPAField = uieditfield(app.InitialconfigurationTab, 'text');
            app.StudyLocationMVPAField.ValueChangedFcn = createCallbackFcn(app, @StudyLocationMVPAFieldValueChanged, true);
            app.StudyLocationMVPAButton = uibutton(app.InitialconfigurationTab, 'push');
            app.StudyLocationMVPAButton.ButtonPushedFcn = createCallbackFcn(app, @studyLocationButtonPushed, true);
            app.StudyLocationMVPAButton.Text = '...';
            app.AnalysistypeLabel = uilabel(app.InitialconfigurationTab);
            app.AnalysistypeLabel.Text = 'Analysis type:';
            app.AnalysistypeDropDown = uidropdown(app.InitialconfigurationTab);
            app.AnalysistypeDropDown.Items = {'Multivariate Patter Analysis (MVPA)', 'Multivariate Cross-Classification (MVCC)'};
            app.AnalysistypeDropDown.ItemsData = {'MVPA', 'MVCC'};
            app.AnalysistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysistypeDropDownValueChanged, true);
            app.AnalysistypeDropDown.Value = 'MVPA';
            app.LoadPreviousStudyPanel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.LoadPreviousStudyPanel.Title = 'Load previous study:';
            app.SelectStudyButton = uibutton(app.LoadPreviousStudyPanel, 'push');
            app.SelectStudyButton.ButtonPushedFcn = createCallbackFcn(app, @selectStudyButtonPushed, true);
            app.SelectStudyButton.Text = '...';
            app.LoadButton = uibutton(app.LoadPreviousStudyPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.OpenstudyfileLabel = uilabel(app.LoadPreviousStudyPanel);
            app.OpenstudyfileLabel.Text = 'Open study file:';
            app.OpenStudyFileEditField = uieditfield(app.LoadPreviousStudyPanel, 'text');
            app.OpenStudyFileEditField.Editable = 'off';
            app.ExitButton = uibutton(app.MVPAlabtoolboxUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @MVPAlabtoolboxUIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.CreatenewstudyLabel = uilabel(app.MVPAlabtoolboxUIFigure);
            app.CreatenewstudyLabel.Text = 'Create new study:';
            app.Panel = uipanel(app.MVPAlabtoolboxUIFigure);
            app.MVPALogo = uiimage(app.Panel);
            app.MVPALogo.ImageSource = 'mvpalab.png';
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.plotUtilityButton = uibutton(app.MVPAlabtoolboxUIFigure, 'push');
            app.plotUtilityButton.ButtonPushedFcn = createCallbackFcn(app, @plotUtilityButtonPushed, true);
            app.plotUtilityButton.Icon = 'graph.png';
            app.plotUtilityButton.Text = 'Open plot result utility';
            % Show the figure after all components are created
            app.MVPAlabtoolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = initView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MVPAlabtoolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MVPAlabtoolboxUIFigure)
        end
    end
end