classdef resultView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        resultViewFigure               matlab.ui.Figure
        refreshButton                  matlab.ui.control.Button
        MVCCdirectionDropDown          matlab.ui.control.DropDown
        MVCCdirectionDropDownLabel     matlab.ui.control.Label
        addToPlotButton                matlab.ui.control.Button
        ColorschemePanel               matlab.ui.container.Panel
        invertColormap                 matlab.ui.control.CheckBox
        colormapSelector               matlab.ui.control.DropDown
        ColorschemeLabel               matlab.ui.control.Label
        AxesPanel                      matlab.ui.container.Panel
        title                          matlab.ui.control.EditField
        TITLEEditFieldLabel            matlab.ui.control.Label
        ylabel                         matlab.ui.control.EditField
        YLABELEditFieldLabel           matlab.ui.control.Label
        xlabel                         matlab.ui.control.EditField
        XLABELEditFieldLabel           matlab.ui.control.Label
        fontSize                       matlab.ui.control.Spinner
        FontsizeSpinnerLabel           matlab.ui.control.Label
        ylim_1                         matlab.ui.control.NumericEditField
        YLIMEditField_2Label           matlab.ui.control.Label
        xlim_1                         matlab.ui.control.NumericEditField
        XLIMEditField_2Label           matlab.ui.control.Label
        ylim_2                         matlab.ui.control.NumericEditField
        xlim_2                         matlab.ui.control.NumericEditField
        TabGroup                       matlab.ui.container.TabGroup
        ModelperformanceTab            matlab.ui.container.Tab
        subjectToPlot                  matlab.ui.control.DropDown
        PleaseselectsubjecttoplotDropDownLabel  matlab.ui.control.Label
        statisticsPanelMean            matlab.ui.container.Panel
        plotBelowCluster               matlab.ui.control.CheckBox
        plotAboveCluster               matlab.ui.control.CheckBox
        significantPointsPosition      matlab.ui.control.Spinner
        PointspositionLabel            matlab.ui.control.Label
        significanceHighlightMode      matlab.ui.control.DropDown
        HighlightmodeDropDownLabel     matlab.ui.control.Label
        linewidth                      matlab.ui.control.Spinner
        WidthLabel_3                   matlab.ui.control.Label
        shadeColor                     matlab.ui.control.DropDown
        LinecolorLabel_3               matlab.ui.control.Label
        linestyle                      matlab.ui.control.DropDown
        LinestyleDropDownLabel         matlab.ui.control.Label
        shadeAlpha                     matlab.ui.control.Spinner
        alphaLabel                     matlab.ui.control.Label
        smoothData                     matlab.ui.control.Spinner
        SmoothdataSpinnerLabel         matlab.ui.control.Label
        errorMetricDropDown            matlab.ui.control.DropDown
        ErrormetricLabel               matlab.ui.control.Label
        TemporalgeneralizationTab      matlab.ui.container.Tab
        ColorbarlabelEditField         matlab.ui.control.EditField
        ColorbarlabelEditFieldLabel    matlab.ui.control.Label
        statisticsPanelTG              matlab.ui.container.Panel
        linewidthBelow                 matlab.ui.control.Spinner
        WidthLabel_2                   matlab.ui.control.Label
        linewidthAbove                 matlab.ui.control.Spinner
        WidthLabel                     matlab.ui.control.Label
        colorBelowChance               matlab.ui.control.DropDown
        LinecolorLabel_2               matlab.ui.control.Label
        colorAboveChance               matlab.ui.control.DropDown
        LinecolorLabel                 matlab.ui.control.Label
        plotBelowClusterTG             matlab.ui.control.CheckBox
        plotAboveClusterTG             matlab.ui.control.CheckBox
        CAXIS1                         matlab.ui.control.NumericEditField
        CAXISEditFieldLabel            matlab.ui.control.Label
        CAXIS2                         matlab.ui.control.NumericEditField
        WeightanalysisTab              matlab.ui.container.Tab
        WeightsDropDown                matlab.ui.control.DropDown
        WeightsDropDownLabel           matlab.ui.control.Label
        plotType                       matlab.ui.container.ButtonGroup
        AnimationspeedSpinner          matlab.ui.control.Spinner
        AnimationspeedSpinnerLabel     matlab.ui.control.Label
        TemporalanimationButton        matlab.ui.control.RadioButton
        TemporalaverageButton          matlab.ui.control.RadioButton
        PlotButton                     matlab.ui.control.Button
        subjectToAnalyze               matlab.ui.control.DropDown
        SubjecttoanalyzeDropDownLabel  matlab.ui.control.Label
        TimewindowtoanalyzePanel       matlab.ui.container.Panel
        msLabel_2                      matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        wEndTime                       matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel          matlab.ui.control.Label
        wStartTime                     matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel        matlab.ui.control.Label
        LoadfiletoanalizePanel         matlab.ui.container.Panel
        selectWeightsFile              matlab.ui.control.Button
        SelectweightsfileEditField     matlab.ui.control.EditField
        SelectweightsfileEditFieldLabel  matlab.ui.control.Label
        plotResutsButton               matlab.ui.control.Button
        resultFilePanel                matlab.ui.container.Panel
        loadResultField                matlab.ui.control.EditField
        ResultfileLabel                matlab.ui.control.Label
        loadResultButton               matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        MVPAlabToolboxLabel            matlab.ui.control.Label
        versionLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        cfg;
        graph;
        openDir = ''; % Description
        resultMatrix;
        wvector;
    end
    
    methods (Access = private)
        
        %% HELPER - UpdateGUI
        function defaultGUI(app)
            
            if strcmp(app.cfg.analysis,'MVCC')
                app.MVCCdirectionDropDown.Visible = 'on';
                app.graph.mvccDirection = 'ab';
            else
                app.MVCCdirectionDropDown.Visible = 'off';
            end
            
            % Subject dropdown:
            nSubjects = size(app.resultMatrix,3);
            for i = 1 : nSubjects
                app.subjectToPlot.Items{i+1} = ['Subject ' num2str(i)];
                app.subjectToPlot.ItemsData{i+1} = num2str(i);
            end
            
            if app.cfg.classmodel.tempgen  
                
                % Temporal generalization:
                app.graph.xlim = [app.cfg.tm.tpstart_ app.cfg.tm.tpend_];
                app.graph.ylim = [app.cfg.tm.tpstart app.cfg.tm.tpend];
                app.graph.title = 'Temporal generalization matrix';
                app.graph.xlabel = 'Test time (ms)';
                app.graph.ylabel = 'Training time (ms)';
                
                app.title.Value = app.graph.title;
                app.xlabel.Value = app.graph.xlabel;
                app.ylabel.Value = app.graph.ylabel;
                app.xlim_1.Value = app.cfg.tm.tpstart_;
                app.xlim_2.Value = app.cfg.tm.tpend_;
                app.ylim_1.Value = app.cfg.tm.tpstart;
                app.ylim_2.Value = app.cfg.tm.tpend;
                
            else


                if app.cfg.sf.flag
                    % Sliding filter:
                    app.graph.xlim = [app.cfg.tm.tpstart app.cfg.tm.tpend];
                    app.graph.ylim = [app.cfg.sf.fcutoff{1}(2) app.cfg.sf.fcutoff{end}(2)];
                    app.graph.title = 'Performance difference map';
                    app.graph.ylabel = 'Removed frequencies (Hz)';
                    app.graph.xlabel = 'Time (ms)';
                    
                    app.title.Value = app.graph.title;
                    app.xlabel.Value = app.graph.xlabel;
                    app.ylabel.Value = app.graph.ylabel;
                    app.xlim_1.Value = app.cfg.tm.tpstart;
                    app.xlim_2.Value = app.cfg.tm.tpend;
                    app.ylim_1.Value = app.graph.ylim(1);
                    app.ylim_2.Value = app.graph.ylim(2);
              
                else
                    % Mean performance:
                    app.graph.xlim = [app.cfg.tm.tpstart app.cfg.tm.tpend];
                    app.graph.ylim = [0 1];
                    app.graph.title = 'Model mean performance';
                    app.graph.ylabel = 'Model performance';
                    app.graph.xlabel = 'Time (ms)';
                    
                    app.title.Value = app.graph.title;
                    app.xlabel.Value = app.graph.xlabel;
                    app.ylabel.Value = app.graph.ylabel;
                    app.xlim_1.Value = app.cfg.tm.tpstart;
                    app.xlim_2.Value = app.cfg.tm.tpend;
                    app.ylim_1.Value = app.graph.ylim(1);
                    app.ylim_2.Value = app.graph.ylim(2);
                end
            end
            app.updateGraph();
        end
        
        %% HELPER - UpdateGUI
        function defaultWGUI(app)
            
            if strcmp(app.cfg.analysis,'MVCC')
                app.MVCCdirectionDropDown.Visible = 'on';
                app.graph.mvccDirection = 'ab';
                w = app.wvector.ab.raw;
            else
                app.MVCCdirectionDropDown.Visible = 'off';
                w = app.wvector.raw;
            end
            
            % Subject dropdown:
            nSubjects = size(w,1);
            for i = 1 : nSubjects
                app.subjectToAnalyze.Items{i+1} = ['Subject ' num2str(i)];
                app.subjectToAnalyze.ItemsData{i+1} = num2str(i);
            end
            
            % Weight dropdown:
            app.WeightsDropDown.Items = {'Raw','Corrected'};
            app.WeightsDropDown.ItemsData = {'raw','haufe_corrected'};
            
            % Time:
            app.wStartTime.Value = app.cfg.tm.tpstart;
            app.wEndTime.Value = app.cfg.tm.tpend;
            
            app.graph.weights.start = app.cfg.tm.tpstart;
            app.graph.weights.end = app.cfg.tm.tpend;
            app.updateGraph();
            
        end
        
        %% HELPER - Get focus
        function getFocus(app)
            app.resultViewFigure.Visible = 'off';
            app.resultViewFigure.Visible = 'on';
        end
        
        %% HELPER - Load results
        function loadResults(app,path,file)
            % Clear the current workspace:
            evalin("base",'clearvars -except graph wvector');
            
            % Load study and result file:
            temp = load([path file]);
            app.cfg = temp.cfg;
            
            % Check cfg integrity:
            app.cfg = mvpalab_checkcfg(app.cfg);
            
            % Load data in the workspace:
            assignin('base','cfg',temp.cfg);
            assignin('base','result',temp.result);
            
            % Load a copy of the result in the GUI:
            app.resultMatrix = temp.result;
            
            % Try to import stats data to base workspace:
            if exist([path filesep 'stats.mat'],'file')
                temp = load([path filesep 'stats.mat']);
                assignin('base','stats',temp.stats);
                app.enableStats('on');
            else
                app.enableStats('off');
            end
            
            % Try to import permuted maps to base workspace:
            if exist([path filesep 'permaps.mat'],'file')
                temp = load([path filesep 'permaps.mat']);
                assignin('base','permaps',temp.permaps);
            end
            
             app.defaultGUI();
        end
        
        %% HELPER - Load weights
        function loadWeights(app,path,file)
            
            % Load study and result file:
            temp = load([path file]);
            app.cfg = temp.cfg;
            
            % Check cfg integrity:
            app.cfg = mvpalab_checkcfg(app.cfg);
            
            % Load data in the workspace:
            assignin('base','cfg',temp.cfg);
            assignin('base','wvector',temp.result);
            
            % Load a copy of the result in the GUI:
            app.wvector = temp.result;
        end
        
        %% HELPER - Update graph structure
        function updateGraph (app)
            assignin('base','graph',app.graph);
        end
        
        %% HELPER - Enable stats panel
        function enableStats (app,value)
            app.plotAboveClusterTG.Enable = value;
            app.plotBelowClusterTG.Enable = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init graph structure and assign to base workspace:
            app.graph = mvpalab_plotinit();
            assignin('base','graph',app.graph);
            app.versionLabel.Text = mvpalab_getversion();
            app.MVCCdirectionDropDown.Visible = 'off';
        end

        % Button pushed function: plotResutsButton
        function plotResutsButtonPushed(app, event)
            app.graph.add = false;
            app.updateGraph();
            evalin("base", 'plotController');
        end

        % Value changed function: colormapSelector
        function colormapSelectorValueChanged(app, event)
            app.graph.colorMap = getfield(app.graph.grads,...
                app.colormapSelector.Value);
            app.graph.colorSch = getfield(app.graph.colors,...
                app.colormapSelector.Value);
            app.graph.shadecolor = app.graph.colorSch{str2double(...
                app.shadeColor.Value)};
            app.updateGraph();
        end

        % Button pushed function: loadResultButton
        function loadResultPushed(app, event)
            % Disable load and plot button:
            app.loadResultButton.Text = 'Loading data...';
            app.loadResultButton.Enable = 'off';
            app.plotResutsButton.Enable = 'off';
            app.addToPlotButton.Enable = 'off';
            
            % File selector:
            if isempty(app.openDir)
                
                % Desktop folder for different operative systems:
                if ismac || isunix
                    env = 'HOME';
                elseif ispc
                    env = 'USERPROFILE';
                end
                
                app.openDir = fullfile(getenv(env),'Desktop');
            end
            
            [file,path] = uigetfile([app.openDir '/result.mat'], ...
                'Select a result.mat file');
            app.getFocus();
            
            if path
                app.openDir = path;
            end
            
            if file
                if strcmp(file,'result.mat')
                    % Update text field
                    app.loadResultField.Value = [path file];
                    
                    % Load data in the base workspace:
                    app.loadResults(path,file);
                    app.defaultGUI();
                    app.getFocus();
                else
                    uialert(app.resultViewFigure,...
                        'Please, select a correct result.mat file',...
                        'Warning');
                end
            else
                % Clear dir:
                app.loadResultField.Value = '';
            end
            
            % Enable load button
            app.loadResultButton.Text = 'Select file';
            app.loadResultButton.Enable = 'on';
            app.plotResutsButton.Enable = 'on';
            app.addToPlotButton.Enable = 'on';
        end

        % Button pushed function: selectWeightsFile
        function selectWeightsPushed(app, event)
            % Disable load and plot button:
            app.selectWeightsFile.Text = 'Loading data...';
            app.selectWeightsFile.Enable = 'off';
            
            % File selector:
            if isempty(app.openDir)
                
                % Desktop folder for different operative systems:
                if ismac || isunix
                    env = 'HOME';
                elseif ispc
                    env = 'USERPROFILE';
                end
                
                app.openDir = fullfile(getenv(env),'Desktop');
            end
            
            [file,path] = uigetfile([app.openDir '/result.mat'], ...
                'Select a result.mat file');
            app.getFocus();
            
            if path
                app.openDir = path;
            end
            
            if file
                if strcmp(file,'result.mat')
                    % Update text field
                    app.SelectweightsfileEditField.Value = [path file];
                    
                    % Load data in the base workspace:
                    app.loadWeights(path,file);
                    app.defaultWGUI();
                    app.getFocus();
                else
                    uialert(app.resultViewFigure,...
                        'Please, select a correct result.mat file',...
                        'Warning');
                end
            else
                % Clear dir:
                app.SelectweightsfileEditField.Value = '';
            end
            
            app.selectWeightsFile.Text = 'Select';
            app.selectWeightsFile.Enable = 'on';
            app.PlotButton.Enable = 'on';
        end

        % Value changed function: plotAboveCluster
        function plotAboveClusterValueChanged(app, event)
            app.plotAboveClusterTG.Value = app.plotAboveCluster.Value;
            app.graph.stats.above = app.plotAboveCluster.Value;
            app.updateGraph();
        end

        % Value changed function: plotBelowCluster
        function plotBelowClusterValueChanged(app, event)
            app.plotBelowClusterTG.Value = app.plotBelowCluster.Value;
            app.graph.stats.below = app.plotBelowCluster.Value;
            app.updateGraph();
        end

        % Value changed function: significanceHighlightMode
        function significanceHighlightModeValueChanged(app, event)
            if strcmp(app.significanceHighlightMode.Value,"Points")
                app.graph.sigmode.shade = 0;
                app.graph.sigmode.points = 1;
            elseif strcmp(app.significanceHighlightMode.Value,"Shade")
                app.graph.sigmode.shade = 1;
                app.graph.sigmode.points = 0;
            end
            app.updateGraph();
        end

        % Value changed function: significantPointsPosition
        function significantPointsPositionValueChanged(app, event)
            app.graph.sigh = app.significantPointsPosition.Value;
            app.updateGraph();
        end

        % Value changed function: colorAboveChance
        function colorAboveChanceValueChanged(app, event)
            selected = app.colorAboveChance.Value;
            app.graph.clusterLineColor(1) = str2double(selected(1));
            app.graph.clusterLineColor(2) = str2double(selected(2));
            app.graph.clusterLineColor(3) = str2double(selected(3));
            app.updateGraph();
        end

        % Value changed function: colorBelowChance
        function colorBelowChanceValueChanged(app, event)
            selected = app.colorBelowChance.Value;
            app.graph.clusterLineColor_(1) = str2double(selected(1));
            app.graph.clusterLineColor_(2) = str2double(selected(2));
            app.graph.clusterLineColor_(3) = str2double(selected(3));
            app.updateGraph();
        end

        % Value changed function: errorMetricDropDown
        function errorMetricDropDownValueChanged(app, event)
            app.graph.stdsem = str2double(app.errorMetricDropDown.Value);
            app.updateGraph();
        end

        % Value changed function: invertColormap
        function invertColormapValueChanged(app, event)
            app.graph.colorMap = flipud(app.graph.colorMap);
            app.updateGraph();
        end

        % Value changed function: shadeAlpha
        function shadeAlphaValueChanged(app, event)
            app.graph.shadealpha = app.shadeAlpha.Value;
            app.updateGraph();
        end

        % Value changed function: smoothData
        function smoothDataValueChanged(app, event)
            app.graph.smoothdata = app.smoothData.Value;
            app.updateGraph();
        end

        % Value changed function: shadeColor
        function shadeColorValueChanged(app, event)
            app.graph.shadecolor = app.graph.colorSch{str2double(...
                app.shadeColor.Value)};
            app.graph.sigc = app.graph.colorSch{str2double(...
                app.shadeColor.Value)};
            app.updateGraph();
        end

        % Value changed function: linestyle
        function linestyleValueChanged(app, event)
            app.graph.linestyle = app.linestyle.Value;
            app.updateGraph();
        end

        % Value changed function: linewidth
        function linewidthValueChanged(app, event)
            app.graph.linewidth = app.linewidth.Value;
            app.updateGraph();
        end

        % Value changed function: linewidthAbove
        function linewidthAboveValueChanged(app, event)
            app.graph.clusterLineWidth = app.linewidthAbove.Value;
            app.updateGraph();
        end

        % Value changed function: linewidthBelow
        function linewidthBelowValueChanged(app, event)
            app.graph.clusterLineWidth_ = app.linewidthBelow.Value;
            app.updateGraph();
        end

        % Value changed function: xlim_1
        function xlim_1ValueChanged(app, event)
            app.graph.xlim(1) = app.xlim_1.Value;
            app.updateGraph();
        end

        % Value changed function: xlim_2
        function xlim_2ValueChanged(app, event)
            app.graph.xlim(2) = app.xlim_2.Value;
            app.updateGraph();
        end

        % Value changed function: ylim_1
        function ylim_1ValueChanged(app, event)
            app.graph.ylim(1) = app.ylim_1.Value;
            app.updateGraph();
        end

        % Value changed function: ylim_2
        function ylim_2ValueChanged(app, event)
            app.graph.ylim(2) = app.ylim_2.Value;
            app.updateGraph();
        end

        % Value changed function: fontSize
        function fontSizeValueChanged(app, event)
            app.graph.fontsize = app.fontSize.Value;
            app.updateGraph();
        end

        % Value changed function: xlabel
        function xlabelValueChanged(app, event)
            app.graph.xlabel = app.xlabel.Value;
            app.updateGraph();
        end

        % Value changed function: ylabel
        function ylabelValueChanged(app, event)
            app.graph.ylabel = app.ylabel.Value;
            app.updateGraph();
        end

        % Value changed function: title
        function titleValueChanged(app, event)
            app.graph.title = app.title.Value;
            app.updateGraph();
        end

        % Value changed function: CAXIS1
        function CAXIS1ValueChanged(app, event)
            app.graph.caxis(1) = app.CAXIS1.Value;
            app.updateGraph();
        end

        % Value changed function: CAXIS2
        function CAXIS2ValueChanged(app, event)
            app.graph.caxis(2) = app.CAXIS2.Value;
            app.updateGraph();
        end

        % Value changed function: plotAboveClusterTG
        function plotAboveClusterTGValueChanged(app, event)
            app.plotAboveCluster.Value = app.plotAboveClusterTG.Value;
            app.graph.stats.above = app.plotAboveClusterTG.Value;
            app.updateGraph();
        end

        % Value changed function: plotBelowClusterTG
        function plotBelowClusterTGValueChanged(app, event)
            app.plotBelowCluster.Value = app.plotBelowClusterTG.Value;
            app.graph.stats.below = app.plotBelowClusterTG.Value;
            app.updateGraph();
        end

        % Button pushed function: addToPlotButton
        function addToPlotButtonPushed(app, event)
            app.graph.add = true;
            app.updateGraph();
            evalin("base", 'plotController');
        end

        % Value changed function: MVCCdirectionDropDown
        function MVCCdirectionDropDownValueChanged(app, event)
            app.graph.mvccDirection = app.MVCCdirectionDropDown.Value;
            app.updateGraph();
        end

        % Value changed function: subjectToPlot
        function subjectToPlotValueChanged(app, event)
            app.graph.subject = str2num(app.subjectToPlot.Value);
            app.updateGraph();
        end

        % Value changed function: WeightsDropDown
        function WeightsDropDownValueChanged(app, event)
            app.graph.weights.type = app.WeightsDropDown.Value;
            app.updateGraph();
        end

        % Value changed function: subjectToAnalyze
        function subjectToAnalyzeValueChanged(app, event)
            app.graph.weights.sub = str2num(app.subjectToAnalyze.Value);
            app.updateGraph();
        end

        % Value changed function: AnimationspeedSpinner
        function AnimationspeedSpinnerValueChanged(app, event)
            app.graph.weights.speed = app.AnimationspeedSpinner.Value;
            app.updateGraph();
        end

        % Value changed function: wStartTime
        function wStartTimeValueChanged(app, event)
            app.graph.weights.start = app.wStartTime.Value;
            app.updateGraph();
        end

        % Value changed function: wEndTime
        function wEndTimeValueChanged(app, event)
            app.graph.weights.end = app.wEndTime.Value;
            app.updateGraph();
        end

        % Selection changed function: plotType
        function plotTypeSelectionChanged(app, event)
            if strcmp(app.plotType.SelectedObject.Text,'Temporal animation')
                app.graph.weights.anim = 1;
            else
                app.graph.weights.anim = 0;
            end
            app.updateGraph();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            evalin("base", 'weightsController');
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            mvpalab_refreshplot(app.graph);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.resultViewFigure = uifigure('Visible', 'off');
            app.resultViewFigure.Name = 'Plot utility';
            app.Panel = uipanel(app.resultViewFigure);
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.resultFilePanel = uipanel(app.resultViewFigure);
            app.resultFilePanel.Title = 'Result file location:';
            app.loadResultButton = uibutton(app.resultFilePanel, 'push');
            app.loadResultButton.ButtonPushedFcn = createCallbackFcn(app, @loadResultPushed, true);
            app.loadResultButton.Text = 'Select file';
            app.ResultfileLabel = uilabel(app.resultFilePanel);
            app.ResultfileLabel.Text = 'Result file:';
            app.loadResultField = uieditfield(app.resultFilePanel, 'text');
            app.loadResultField.Editable = 'off';
            app.plotResutsButton = uibutton(app.resultViewFigure, 'push');
            app.plotResutsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResutsButtonPushed, true);
            app.plotResutsButton.Enable = 'off';
            app.plotResutsButton.Text = 'Plot results';
            app.TabGroup = uitabgroup(app.resultViewFigure);
            app.ModelperformanceTab = uitab(app.TabGroup);
            app.ModelperformanceTab.Title = 'Model performance';
            app.ErrormetricLabel = uilabel(app.ModelperformanceTab);
            app.ErrormetricLabel.Text = 'Error metric:';
            app.errorMetricDropDown = uidropdown(app.ModelperformanceTab);
            app.errorMetricDropDown.Items = {'Standard deviation (STD)', 'Standard error mean (SEM)'};
            app.errorMetricDropDown.ItemsData = {'1', '0'};
            app.errorMetricDropDown.ValueChangedFcn = createCallbackFcn(app, @errorMetricDropDownValueChanged, true);
            app.errorMetricDropDown.Value = '0';
            app.SmoothdataSpinnerLabel = uilabel(app.ModelperformanceTab);
            app.SmoothdataSpinnerLabel.Text = 'Smooth data:';
            app.smoothData = uispinner(app.ModelperformanceTab);
            app.smoothData.Limits = [1 Inf];
            app.smoothData.ValueChangedFcn = createCallbackFcn(app, @smoothDataValueChanged, true);
            app.smoothData.Value = 1;
            app.alphaLabel = uilabel(app.ModelperformanceTab);
            app.alphaLabel.Text = 'alpha:';
            app.shadeAlpha = uispinner(app.ModelperformanceTab);
            app.shadeAlpha.Step = 0.1;
            app.shadeAlpha.Limits = [0 1];
            app.shadeAlpha.ValueChangedFcn = createCallbackFcn(app, @shadeAlphaValueChanged, true);
            app.shadeAlpha.Value = 0.5;
            app.LinestyleDropDownLabel = uilabel(app.ModelperformanceTab);
            app.LinestyleDropDownLabel.Text = 'Line style:';
            app.linestyle = uidropdown(app.ModelperformanceTab);
            app.linestyle.Items = {'none', 'solid', 'dashed', 'dotted', 'dash dot'};
            app.linestyle.ItemsData = {'none', '-', '--', ':', '-.'};
            app.linestyle.ValueChangedFcn = createCallbackFcn(app, @linestyleValueChanged, true);
            app.linestyle.Value = '-';
            app.LinecolorLabel_3 = uilabel(app.ModelperformanceTab);
            app.LinecolorLabel_3.Text = 'Line color:';
            app.shadeColor = uidropdown(app.ModelperformanceTab);
            app.shadeColor.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.shadeColor.ValueChangedFcn = createCallbackFcn(app, @shadeColorValueChanged, true);
            app.shadeColor.Value = '1';
            app.WidthLabel_3 = uilabel(app.ModelperformanceTab);
            app.WidthLabel_3.Text = 'Width:';
            app.linewidth = uispinner(app.ModelperformanceTab);
            app.linewidth.Step = 0.5;
            app.linewidth.Limits = [0.5 15];
            app.linewidth.ValueChangedFcn = createCallbackFcn(app, @linewidthValueChanged, true);
            app.linewidth.Value = 1;
            app.statisticsPanelMean = uipanel(app.ModelperformanceTab);
            app.statisticsPanelMean.Title = 'Statistics:';
            app.HighlightmodeDropDownLabel = uilabel(app.statisticsPanelMean);
            app.HighlightmodeDropDownLabel.Text = 'Highlight mode:';
            app.significanceHighlightMode = uidropdown(app.statisticsPanelMean);
            app.significanceHighlightMode.Items = {'Shade', 'Points'};
            app.significanceHighlightMode.ValueChangedFcn = createCallbackFcn(app, @significanceHighlightModeValueChanged, true);
            app.significanceHighlightMode.Value = 'Points';
            app.PointspositionLabel = uilabel(app.statisticsPanelMean);
            app.PointspositionLabel.Text = 'Points position:';
            app.significantPointsPosition = uispinner(app.statisticsPanelMean);
            app.significantPointsPosition.Step = 0.1;
            app.significantPointsPosition.Limits = [-1.5 1.5];
            app.significantPointsPosition.ValueChangedFcn = createCallbackFcn(app, @significantPointsPositionValueChanged, true);
            app.significantPointsPosition.Value = 0.5;
            app.plotAboveCluster = uicheckbox(app.statisticsPanelMean);
            app.plotAboveCluster.ValueChangedFcn = createCallbackFcn(app, @plotAboveClusterValueChanged, true);
            app.plotAboveCluster.Text = 'Highlight above chance significant clusters';
            app.plotBelowCluster = uicheckbox(app.statisticsPanelMean);
            app.plotBelowCluster.ValueChangedFcn = createCallbackFcn(app, @plotBelowClusterValueChanged, true);
            app.plotBelowCluster.Text = 'Highlight below chance significant clusters';
            app.PleaseselectsubjecttoplotDropDownLabel = uilabel(app.ModelperformanceTab);
            app.PleaseselectsubjecttoplotDropDownLabel.Text = 'Please select subject to plot:';
            app.subjectToPlot = uidropdown(app.ModelperformanceTab);
            app.subjectToPlot.Items = {'Mean'};
            app.subjectToPlot.ItemsData = {'0'};
            app.subjectToPlot.ValueChangedFcn = createCallbackFcn(app, @subjectToPlotValueChanged, true);
            app.subjectToPlot.Value = '0';
            app.TemporalgeneralizationTab = uitab(app.TabGroup);
            app.TemporalgeneralizationTab.Title = 'Temporal generalization';
            app.CAXIS2 = uieditfield(app.TemporalgeneralizationTab, 'numeric');
            app.CAXIS2.ValueChangedFcn = createCallbackFcn(app, @CAXIS2ValueChanged, true);
            app.CAXISEditFieldLabel = uilabel(app.TemporalgeneralizationTab);
            app.CAXISEditFieldLabel.Text = 'CAXIS:';
            app.CAXIS1 = uieditfield(app.TemporalgeneralizationTab, 'numeric');
            app.CAXIS1.ValueChangedFcn = createCallbackFcn(app, @CAXIS1ValueChanged, true);
            app.statisticsPanelTG = uipanel(app.TemporalgeneralizationTab);
            app.statisticsPanelTG.Title = 'Statistics:';
            app.plotAboveClusterTG = uicheckbox(app.statisticsPanelTG);
            app.plotAboveClusterTG.ValueChangedFcn = createCallbackFcn(app, @plotAboveClusterTGValueChanged, true);
            app.plotAboveClusterTG.Text = 'Highlight above chance significant clusters';
            app.plotBelowClusterTG = uicheckbox(app.statisticsPanelTG);
            app.plotBelowClusterTG.ValueChangedFcn = createCallbackFcn(app, @plotBelowClusterTGValueChanged, true);
            app.plotBelowClusterTG.Text = 'Highlight below chance significant clusters';
            app.LinecolorLabel = uilabel(app.statisticsPanelTG);
            app.LinecolorLabel.Text = 'Line color:';
            app.colorAboveChance = uidropdown(app.statisticsPanelTG);
            app.colorAboveChance.Items = {'Black', 'White', 'Red', 'Green', 'Blue'};
            app.colorAboveChance.ItemsData = {'000', '111', '100', '010', '001'};
            app.colorAboveChance.ValueChangedFcn = createCallbackFcn(app, @colorAboveChanceValueChanged, true);
            app.colorAboveChance.Value = '111';
            app.LinecolorLabel_2 = uilabel(app.statisticsPanelTG);
            app.LinecolorLabel_2.Text = 'Line color:';
            app.colorBelowChance = uidropdown(app.statisticsPanelTG);
            app.colorBelowChance.Items = {'Black', 'White', 'Red', 'Green', 'Blue'};
            app.colorBelowChance.ItemsData = {'000', '111', '100', '010', '001'};
            app.colorBelowChance.ValueChangedFcn = createCallbackFcn(app, @colorBelowChanceValueChanged, true);
            app.colorBelowChance.Value = '000';
            app.WidthLabel = uilabel(app.statisticsPanelTG);
            app.WidthLabel.Text = 'Width:';
            app.linewidthAbove = uispinner(app.statisticsPanelTG);
            app.linewidthAbove.Step = 0.5;
            app.linewidthAbove.Limits = [0.5 15];
            app.linewidthAbove.ValueChangedFcn = createCallbackFcn(app, @linewidthAboveValueChanged, true);
            app.linewidthAbove.Value = 1;
            app.WidthLabel_2 = uilabel(app.statisticsPanelTG);
            app.WidthLabel_2.Text = 'Width:';
            app.linewidthBelow = uispinner(app.statisticsPanelTG);
            app.linewidthBelow.Step = 0.5;
            app.linewidthBelow.Limits = [0.5 15];
            app.linewidthBelow.ValueChangedFcn = createCallbackFcn(app, @linewidthBelowValueChanged, true);
            app.linewidthBelow.Value = 1;
            app.ColorbarlabelEditFieldLabel = uilabel(app.TemporalgeneralizationTab);
            app.ColorbarlabelEditFieldLabel.Enable = 'off';
            app.ColorbarlabelEditFieldLabel.Text = 'Colorbar label:';
            app.ColorbarlabelEditField = uieditfield(app.TemporalgeneralizationTab, 'text');
            app.ColorbarlabelEditField.Enable = 'off';
            app.WeightanalysisTab = uitab(app.TabGroup);
            app.WeightanalysisTab.Title = 'Weight analysis';
            app.LoadfiletoanalizePanel = uipanel(app.WeightanalysisTab);
            app.LoadfiletoanalizePanel.Title = 'Load file to analize:';
            app.SelectweightsfileEditFieldLabel = uilabel(app.LoadfiletoanalizePanel);
            app.SelectweightsfileEditFieldLabel.Text = 'Select weights file:';
            app.SelectweightsfileEditField = uieditfield(app.LoadfiletoanalizePanel, 'text');
            app.SelectweightsfileEditField.Editable = 'off';
            app.selectWeightsFile = uibutton(app.LoadfiletoanalizePanel, 'push');
            app.selectWeightsFile.ButtonPushedFcn = createCallbackFcn(app, @selectWeightsPushed, true);
            app.selectWeightsFile.Text = 'Select';
            app.TimewindowtoanalyzePanel = uipanel(app.WeightanalysisTab);
            app.TimewindowtoanalyzePanel.Title = 'Time window to analyze:';
            app.StarttimeEditFieldLabel = uilabel(app.TimewindowtoanalyzePanel);
            app.StarttimeEditFieldLabel.Text = 'Start time:';
            app.wStartTime = uieditfield(app.TimewindowtoanalyzePanel, 'numeric');
            app.wStartTime.ValueChangedFcn = createCallbackFcn(app, @wStartTimeValueChanged, true);
            app.EndtimeEditFieldLabel = uilabel(app.TimewindowtoanalyzePanel);
            app.EndtimeEditFieldLabel.Text = 'End time:';
            app.wEndTime = uieditfield(app.TimewindowtoanalyzePanel, 'numeric');
            app.wEndTime.ValueChangedFcn = createCallbackFcn(app, @wEndTimeValueChanged, true);
            app.msLabel = uilabel(app.TimewindowtoanalyzePanel);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.TimewindowtoanalyzePanel);
            app.msLabel_2.Text = 'ms';
            app.SubjecttoanalyzeDropDownLabel = uilabel(app.WeightanalysisTab);
            app.SubjecttoanalyzeDropDownLabel.Text = 'Subject to analyze:';
            app.subjectToAnalyze = uidropdown(app.WeightanalysisTab);
            app.subjectToAnalyze.Items = {'Mean'};
            app.subjectToAnalyze.ItemsData = {'0'};
            app.subjectToAnalyze.ValueChangedFcn = createCallbackFcn(app, @subjectToAnalyzeValueChanged, true);
            app.subjectToAnalyze.Value = '0';
            app.PlotButton = uibutton(app.WeightanalysisTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = 'play_.png';
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.plotType = uibuttongroup(app.WeightanalysisTab);
            app.plotType.SelectionChangedFcn = createCallbackFcn(app, @plotTypeSelectionChanged, true);
            app.plotType.Title = 'Plot configuration:';
            app.TemporalaverageButton = uiradiobutton(app.plotType);
            app.TemporalaverageButton.Text = 'Temporal average';
            app.TemporalaverageButton.Value = true;
            app.TemporalanimationButton = uiradiobutton(app.plotType);
            app.TemporalanimationButton.Text = 'Temporal animation';
            app.AnimationspeedSpinnerLabel = uilabel(app.plotType);
            app.AnimationspeedSpinnerLabel.Text = 'Animation speed:';
            app.AnimationspeedSpinner = uispinner(app.plotType);
            app.AnimationspeedSpinner.Limits = [1 10];
            app.AnimationspeedSpinner.ValueChangedFcn = createCallbackFcn(app, @AnimationspeedSpinnerValueChanged, true);
            app.AnimationspeedSpinner.Value = 5;
            app.WeightsDropDownLabel = uilabel(app.WeightanalysisTab);
            app.WeightsDropDownLabel.Text = 'Weights:';
            app.WeightsDropDown = uidropdown(app.WeightanalysisTab);
            app.WeightsDropDown.Items = {'Select'};
            app.WeightsDropDown.ItemsData = {'0'};
            app.WeightsDropDown.ValueChangedFcn = createCallbackFcn(app, @WeightsDropDownValueChanged, true);
            app.WeightsDropDown.Value = '0';
            app.AxesPanel = uipanel(app.resultViewFigure);
            app.AxesPanel.Title = 'Axes:';
            app.xlim_2 = uieditfield(app.AxesPanel, 'numeric');
            app.xlim_2.ValueChangedFcn = createCallbackFcn(app, @xlim_2ValueChanged, true);
            app.ylim_2 = uieditfield(app.AxesPanel, 'numeric');
            app.ylim_2.ValueChangedFcn = createCallbackFcn(app, @ylim_2ValueChanged, true);
            app.ylim_2.Value = 1;
            app.XLIMEditField_2Label = uilabel(app.AxesPanel);
            app.XLIMEditField_2Label.Text = 'XLIM:';
            app.xlim_1 = uieditfield(app.AxesPanel, 'numeric');
            app.xlim_1.ValueChangedFcn = createCallbackFcn(app, @xlim_1ValueChanged, true);
            app.YLIMEditField_2Label = uilabel(app.AxesPanel);
            app.YLIMEditField_2Label.Text = 'YLIM:';
            app.ylim_1 = uieditfield(app.AxesPanel, 'numeric');
            app.ylim_1.ValueChangedFcn = createCallbackFcn(app, @ylim_1ValueChanged, true);
            app.FontsizeSpinnerLabel = uilabel(app.AxesPanel);
            app.FontsizeSpinnerLabel.Text = 'Font size:';
            app.fontSize = uispinner(app.AxesPanel);
            app.fontSize.Step = 2;
            app.fontSize.Limits = [6 38];
            app.fontSize.ValueChangedFcn = createCallbackFcn(app, @fontSizeValueChanged, true);
            app.fontSize.Value = 14;
            app.XLABELEditFieldLabel = uilabel(app.AxesPanel);
            app.XLABELEditFieldLabel.Text = 'XLABEL:';
            app.xlabel = uieditfield(app.AxesPanel, 'text');
            app.xlabel.ValueChangedFcn = createCallbackFcn(app, @xlabelValueChanged, true);
            app.xlabel.Value = 'default label';
            app.YLABELEditFieldLabel = uilabel(app.AxesPanel);
            app.YLABELEditFieldLabel.Text = 'YLABEL:';
            app.ylabel = uieditfield(app.AxesPanel, 'text');
            app.ylabel.ValueChangedFcn = createCallbackFcn(app, @ylabelValueChanged, true);
            app.ylabel.Value = 'default label';
            app.TITLEEditFieldLabel = uilabel(app.AxesPanel);
            app.TITLEEditFieldLabel.Text = 'TITLE:';
            app.title = uieditfield(app.AxesPanel, 'text');
            app.title.ValueChangedFcn = createCallbackFcn(app, @titleValueChanged, true);
            app.title.Value = 'MVPAlab - default figure';
            app.ColorschemePanel = uipanel(app.resultViewFigure);
            app.ColorschemePanel.Title = 'Color scheme:';
            app.ColorschemeLabel = uilabel(app.ColorschemePanel);
            app.ColorschemeLabel.Text = 'Color scheme:';
            app.colormapSelector = uidropdown(app.ColorschemePanel);
            app.colormapSelector.Items = {'MVPAlab', 'Fusion', 'Sun', 'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.colormapSelector.ItemsData = {'mvpalab', 'fusion', 'sun', 'mercury', 'venus', 'earth', 'mars', 'jupiter', 'saturn', 'uranus', 'neptune', ''};
            app.colormapSelector.ValueChangedFcn = createCallbackFcn(app, @colormapSelectorValueChanged, true);
            app.colormapSelector.Value = 'mvpalab';
            app.invertColormap = uicheckbox(app.ColorschemePanel);
            app.invertColormap.ValueChangedFcn = createCallbackFcn(app, @invertColormapValueChanged, true);
            app.invertColormap.Text = 'Invert color scheme';
            app.addToPlotButton = uibutton(app.resultViewFigure, 'push');
            app.addToPlotButton.ButtonPushedFcn = createCallbackFcn(app, @addToPlotButtonPushed, true);
            app.addToPlotButton.Enable = 'off';
            app.addToPlotButton.Text = 'Add to plot';
            app.MVCCdirectionDropDownLabel = uilabel(app.resultViewFigure);
            app.MVCCdirectionDropDownLabel.Text = 'MVCC direction:';
            app.MVCCdirectionDropDown = uidropdown(app.resultViewFigure);
            app.MVCCdirectionDropDown.Items = {'Train: A - Test: B', 'Train: B - Test: A'};
            app.MVCCdirectionDropDown.ItemsData = {'ab', 'ba'};
            app.MVCCdirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MVCCdirectionDropDownValueChanged, true);
            app.MVCCdirectionDropDown.Value = 'ab';
            app.refreshButton = uibutton(app.resultViewFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Icon = 'refresh_.png';
            app.refreshButton.Text = '';
            % Show the figure after all components are created
            app.resultViewFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.resultViewFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.resultViewFigure)
        end
    end
end